classdef ribbonAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PreliminaryViewingTab           matlab.ui.container.Tab
        ShowCirclesButton               matlab.ui.control.StateButton
        ContrastSliderLabel             matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        BrightnessSliderLabel           matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        UIAxes13                        matlab.ui.control.UIAxes
        UIAxes14                        matlab.ui.control.UIAxes
        UIAxes15                        matlab.ui.control.UIAxes
        SetCirclesdoNOTexitthiswindowButton  matlab.ui.control.Button
        UseCirclesButton                matlab.ui.control.Button
        AfterAnalysisTab                matlab.ui.container.Tab
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes12                        matlab.ui.control.UIAxes
        CheckThatDataDropDownLabel      matlab.ui.control.Label
        CheckThatDataDropDown           matlab.ui.control.DropDown
        CheckData3DDropDownLabel        matlab.ui.control.Label
        CheckData3DDropDown             matlab.ui.control.DropDown
        NewSliceValidationDropDownLabel  matlab.ui.control.Label
        NewSliceValidationDropDown      matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        TiffSliceValidationDropDownLabel  matlab.ui.control.Label
        TiffSliceValidationDropDown     matlab.ui.control.DropDown
        DGraphTab                       matlab.ui.container.Tab
        UIAxes16                        matlab.ui.control.UIAxes
        Graph3DDropDownLabel            matlab.ui.control.Label
        Graph3DDropDown                 matlab.ui.control.DropDown
        BackgroundDropDownLabel         matlab.ui.control.Label
        BackgroundDropDown              matlab.ui.control.DropDown
        NoOverlapTab                    matlab.ui.container.Tab
        UIAxes17                        matlab.ui.control.UIAxes
        GraphDropDownLabel              matlab.ui.control.Label
        GraphDropDown                   matlab.ui.control.DropDown
        UpdateRibbonsButton             matlab.ui.control.Button
        RepresentativeTab               matlab.ui.container.Tab
        UIAxes18                        matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        NThreshEditFieldLabel           matlab.ui.control.Label
        NThreshEditField                matlab.ui.control.NumericEditField
        PreThreshEditFieldLabel         matlab.ui.control.Label
        PreThreshEditField              matlab.ui.control.NumericEditField
        PostThreshEditFieldLabel        matlab.ui.control.Label
        PostThreshEditField             matlab.ui.control.NumericEditField
        NMinRangeEditFieldLabel         matlab.ui.control.Label
        NMinRangeEditField              matlab.ui.control.NumericEditField
        NMaxRangeEditFieldLabel         matlab.ui.control.Label
        NMaxRangeEditField              matlab.ui.control.NumericEditField
        RMinRangeEditFieldLabel         matlab.ui.control.Label
        RMinRangeEditField              matlab.ui.control.NumericEditField
        RMaxRangeEditFieldLabel         matlab.ui.control.Label
        RMaxRangeEditField              matlab.ui.control.NumericEditField
        NChEditFieldLabel               matlab.ui.control.Label
        NChEditField                    matlab.ui.control.NumericEditField
        PreChEditFieldLabel             matlab.ui.control.Label
        PreChEditField                  matlab.ui.control.NumericEditField
        PostChEditFieldLabel            matlab.ui.control.Label
        PostChEditField                 matlab.ui.control.NumericEditField
        LoadDataButton                  matlab.ui.control.Button
        LookatSliceSpinnerLabel         matlab.ui.control.Label
        LookatSliceSpinner              matlab.ui.control.Spinner
        NSensitivityEditFieldLabel      matlab.ui.control.Label
        NSensitivityEditField           matlab.ui.control.NumericEditField
        Graph1sliceButton               matlab.ui.control.Button
        LastChEditFieldLabel            matlab.ui.control.Label
        LastChEditField                 matlab.ui.control.NumericEditField
        NMedEditFieldLabel              matlab.ui.control.Label
        NMedEditField                   matlab.ui.control.NumericEditField
        NRadmaxEditFieldLabel           matlab.ui.control.Label
        NRadmaxEditField                matlab.ui.control.EditField
        RRadEditFieldLabel              matlab.ui.control.Label
        RRadEditField                   matlab.ui.control.EditField
        RMedEditFieldLabel              matlab.ui.control.Label
        RMedEditField                   matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel           matlab.ui.control.Label
        EpsilonEditField                matlab.ui.control.NumericEditField
        MinRsizeEditFieldLabel          matlab.ui.control.Label
        MinRsizeEditField               matlab.ui.control.NumericEditField
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        NFirstEditFieldLabel            matlab.ui.control.Label
        NFirstEditField                 matlab.ui.control.NumericEditField
        RFirstEditFieldLabel            matlab.ui.control.Label
        RFirstEditField                 matlab.ui.control.NumericEditField
        RLastEditFieldLabel             matlab.ui.control.Label
        RLastEditField                  matlab.ui.control.NumericEditField
        NLastEditFieldLabel             matlab.ui.control.Label
        NLastEditField                  matlab.ui.control.NumericEditField
        TextArea_2                      matlab.ui.control.TextArea
        MoreSettingsTab                 matlab.ui.container.Tab
        GraphSliceSpinnerLabel          matlab.ui.control.Label
        GraphSliceSpinner               matlab.ui.control.Spinner
        SaveToEditFieldLabel            matlab.ui.control.Label
        SaveToEditField                 matlab.ui.control.EditField
        SavetoNewFolderButton           matlab.ui.control.Button
        UpdatestartingInfoButton        matlab.ui.control.Button
        loaddatafromworkspaceButton     matlab.ui.control.Button
        LoadinMiniDataSpinnerLabel      matlab.ui.control.Label
        LoadinMiniDataSpinner           matlab.ui.control.Spinner
        SavesettingsfromMiniDataButton  matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        StatusLabel                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        newCenters;
        mu;
        ribbon;
        data;
        sliceNumber;
        dataFile;
        startValue;
        stopValue;
        xRot;
        yRot;
        thresholdN;
        thresholdR1;
        thresholdR2;
        range1;
        range2;
        nSlice;
        r1Slice;
        r2Slice;
        filteredData;
        tiffData
        plainData
        voxel
        dimensions
        channel
        minMax
        allData
        S1
        S2
        P13
        P14
        P15
        P13Original
        P13BrightnessAdjusted
        
        NAssociated
        RAssociated
        
        allFiltered
        presynapticPositions
        postsynapticPositions
        
        discardedN
        discardedRPre
        discardedRPost
        
        S3
        S4
        S5
        S6
        S7
        S8
        P4
        P5
        P6
        graphPre
        graphPost
        graphNuc
        MLFigure
        
        channelArray
        Scat3D
        
        fileNum
        R1Scat
        R2Scat
        imageSize
        resizedAllData
        resizedVoxel
        resizedDimensions
    end
    
    methods (Access = private)
        
        function graphOne(app)
            
            
            if app.ChannelDropDown.Value=='1'
                
                [app.S1,app.S2,app.P13,app.P14,app.P15]=initialViewingNucleus(app.plainData(:,:,app.LookatSliceSpinner.Value,app.NChEditField.Value),app.NThreshEditField.Value,[app.NMedEditField.Value,app.NMedEditField.Value], ...
                    [app.NMinRangeEditField.Value,app.NMaxRangeEditField.Value],[str2double(app.NRadmaxEditField.Value)-40,str2double(app.NRadmaxEditField.Value)] ...
                    ,app.NSensitivityEditField.Value,app.UIAxes13,app.UIAxes14,app.UIAxes15,app.minMax(1,1),app.minMax(1,2));
                
                
                app.P13Original=get(app.P13,'Cdata');
                app.P13BrightnessAdjusted=get(app.P13,'Cdata');
                
                [app.P13BrightnessAdjusted,app.P13.CData]=brightnessFunc(app,app.BrightnessSlider.Value,app.P13Original);
                showCircles(app,app.ShowCirclesButton.Value);
                
            elseif app.ChannelDropDown.Value=='2'
                [app.S1,app.S2,app.P13,app.P14,app.P15]=initialViewingRibbon(app.UIAxes13,app.UIAxes14,app.UIAxes15,app.plainData(:,:,app.LookatSliceSpinner.Value,app.PreChEditField.Value),[app.RMedEditField.Value,app.RMedEditField.Value], ...
                    app.PreThreshEditField.Value,app.EpsilonEditField.Value,app.MinRsizeEditField.Value,[app.RMinRangeEditField.Value,app.RMaxRangeEditField.Value] ...
                    ,str2double(app.RRadEditField.Value),app.minMax(2,1),app.minMax(2,2));
                
                app.P13Original=get(app.P13,'Cdata');
                app.P13BrightnessAdjusted=get(app.P13,'Cdata');
                
                [app.P13BrightnessAdjusted,app.P13.CData]=brightnessFunc(app,app.BrightnessSlider.Value,app.P13Original);
                showCircles(app,app.ShowCirclesButton.Value);

            elseif app.ChannelDropDown.Value=='3'
                
                [app.S1,app.S2,app.P13,app.P14,app.P15]=initialViewingRibbon(app.UIAxes13,app.UIAxes14,app.UIAxes15,app.plainData(:,:,app.LookatSliceSpinner.Value,app.PostChEditField.Value),[app.RMedEditField.Value,app.RMedEditField.Value], ...
                    app.PostThreshEditField.Value,app.EpsilonEditField.Value,app.MinRsizeEditField.Value,[app.RMinRangeEditField.Value,app.RMaxRangeEditField.Value] ...
                    ,str2double(app.RRadEditField.Value),app.minMax(3,1),app.minMax(3,2));
                
                    app.P13Original=get(app.P13,'Cdata');
                app.P13BrightnessAdjusted=get(app.P13,'Cdata');
                
                [app.P13BrightnessAdjusted,app.P13.CData]=brightnessFunc(app,app.BrightnessSlider.Value,app.P13Original);
                showCircles(app,app.ShowCirclesButton.Value);

            else 
                
            end
        end
        
        function final=contrastFunc(app,changingValue,brightnessAdjusted)
            final=imadjust(brightnessAdjusted,[changingValue,1-changingValue]);
            
        end
        
        function [brightnessAdjusted,finalData]=brightnessFunc(app,changingValue,original)
            brightnessAdjusted=min(original+changingValue,1);
            %app.P13.CData=app.P13BrightnessAdjusted;
            finalData=contrastFunc(app,app.ContrastSlider.Value,brightnessAdjusted);
        end
        
        function showCircles(app,value)
            
            if isgraphics(app.S1,1)
                markerType=["o","none"];
                app.S1.Marker=markerType(value+1);
                app.S2.Marker=markerType(value+1);
            end
        end
        
        function graphFinal(app,slice,channel)
            
            
            [Radii,Centers,DCircles,DRadii]=findStats(app,channel,slice);
            
            
            app.channelArray=[app.NChEditField.Value,app.PreChEditField.Value,app.PostChEditField.Value];
            
            [~,Data]=brightnessFunc(app,app.BrightnessSlider.Value,app.plainData(:,:,slice,app.channelArray(channel))/(max(app.plainData(:,:,slice,app.channelArray(channel)),[],'all')));
            Filtered=app.filteredData(:,:,slice,app.channelArray(channel));
            
            
            if size(DCircles,1)>0
                [app.S3,app.S4,app.P4,app.P5,app.P6]=graphInitial(app.UIAxes10,app.UIAxes11,app.UIAxes12,Data,Filtered,Centers,Radii);
                [app.S5,app.S6]=graphCircles(app.UIAxes10,app.UIAxes12,DCircles,DRadii,'r');
            else
                [~,~,app.P4,app.P5,app.P6]=graphInitial(app.UIAxes10,app.UIAxes11,app.UIAxes12,Data,Filtered,Centers,Radii);
                [app.S5,app.S6]=graphCircles(app.UIAxes10,app.UIAxes12,DCircles,DRadii,'r');
            end
           
            
        end
        
        function associatedChanged(app)
            set(app.NAssociated,'AlphaData',0,'FaceAlpha',0,'EdgeAlpha',0);
            for b = 1:2
                for i = 1:size(app.NAssociated,1)
                    set(app.RAssociated(b).associated(i).gobject,'AlphaData',0,'FaceAlpha',0,'EdgeAlpha',0);
                end
            end
            if app.GraphDropDown.Value==1
                set(app.NAssociated,'AlphaData',1,'FaceAlpha',1);
                for b = 1:2
                for i = 1:size(app.NAssociated,1)
                    set(app.RAssociated(b).associated(i).gobject,'AlphaData',1,'FaceAlpha',1);
                end
                end
            else
                set(app.NAssociated(app.GraphDropDown.Value-1),'AlphaData',1,'FaceAlpha',1);
                for b = 1:2
                    set(app.RAssociated(b).associated(app.GraphDropDown.Value-1).gobject,'AlphaData',1,'FaceAlpha',1);
                end
            end


        end
        
        function associatedRun(app)
            A=[];
            for i = 1:size(app.NAssociated,1)
                A=[A;sprintf("Associated set # %i",i)];
            end
            
            app.GraphDropDown.Items=["All Associated Nuclei";A];
            app.GraphDropDown.ItemsData=[1:i+1];
        end
        
        function toGraphML(app)
           
           app.MLFigure= graphMachineLearning(app.P13,app.S1);
           
        end


        function updateT45(app)
            [app.NAssociated,app.RAssociated]=associateNR(app,app.ribbon,app.newCenters,app.mu,app.voxel,app.UIAxes17,str2double(app.RRadEditField.Value));
            app.UIAxes17.XLabel.String='X(micron)';
            app.UIAxes17.YLabel.String='Y(micron)';
            app.UIAxes17.ZLabel.String='Z(micron)';
            associatedRun(app);
            [app.R1Scat,app.R2Scat]=representNAndR(app.NAssociated,app.RAssociated,app.UIAxes18);
            app.UIAxes18.XLabel.String='X(micron)';
            app.UIAxes18.YLabel.String='Y(micron)';
            app.UIAxes18.ZLabel.String='Z(micron)';
            app.channelArray=[app.NChEditField.Value,app.PreChEditField.Value,app.PostChEditField.Value];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function FilenameEditFieldValueChanged(app, event)
         
        end

        % Button pushed function: UpdatestartingInfoButton
        function UpdatestartingInfoButtonPushed(app, event)
           app.TextArea.Value="Calculating...";
                       pause(.2);

                    app.channelArray=[app.NChEditField.Value,app.PreChEditField.Value,app.PostChEditField.Value];


            [app.filteredData,app.newCenters,app.mu,app.presynapticPositions,app.postsynapticPositions,app.discardedN,app.discardedRPre,app.discardedRPost,app.ribbon]=analyzeData( ...
                app.NChEditField.Value,app.PreChEditField.Value,app.PostChEditField.Value,[app.NThreshEditField.Value, ...
                app.PreThreshEditField.Value,app.PostThreshEditField.Value,.1],[app.NMedEditField.Value,app.NMedEditField.Value;app.RMedEditField.Value,app.RMedEditField.Value;app.RMedEditField.Value,app.RMedEditField.Value;6,6], ...
                app.NFirstEditField.Value,app.NLastEditField.Value,app.NSensitivityEditField.Value,[app.NMinRangeEditField.Value,app.NMaxRangeEditField.Value],[app.RMinRangeEditField.Value,app.RMaxRangeEditField.Value], ...
                [str2double(app.NRadmaxEditField.Value)-40,str2double(app.NRadmaxEditField.Value)],app.EpsilonEditField.Value,app.MinRsizeEditField.Value,app.voxel,app.dimensions,app.plainData,app.RFirstEditField.Value,app.RLastEditField.Value);
            
                app.newCenters=app.newCenters;
                app.mu=app.mu;
                app.imageSize=500;
                [app.resizedAllData,app.resizedDimensions,app.resizedVoxel]=resizeImage(app.plainData,app.dimensions,app.voxel,app.imageSize,app.imageSize);
            
            app.TextArea.Value="Done calculating.";
            
        end

        % Callback function
        function xrotationEditFieldValueChanged(app, event)


            value = app.xrotationEditField.Value;
            app.xRot=value;
        end

        % Callback function
        function yrotationEditFieldValueChanged(app, event)
            value = app.yrotationEditField.Value;
            app.yRot=value;
        end

        % Value changed function: NThreshEditField
        function NThreshEditFieldValueChanged(app, event)
            value = app.NThreshEditField.Value;
            app.thresholdN=value;
        end

        % Value changed function: PreThreshEditField
        function PreThreshEditFieldValueChanged(app, event)
            value = app.PreThreshEditField.Value;
            app.thresholdR1=value;
        end

        % Value changed function: PostThreshEditField
        function PostThreshEditFieldValueChanged(app, event)
            value = app.PostThreshEditField.Value;
            app.thresholdR2=value;
        end

        % Value changed function: NFirstEditField
        function NFirstEditFieldValueChanged(app, event)
            value = app.NFirstEditField.Value;
            app.startValue=value;
        end

        % Value changed function: NLastEditField
        function NLastEditFieldValueChanged(app, event)
            value = app.NLastEditField.Value;
            app.stopValue=value;
        end

        % Value changed function: RMinRangeEditField
        function RMinRangeEditFieldValueChanged(app, event)
            value = app.RMinRangeEditField.Value;
            app.range2(1)=value;
        end

        % Value changed function: NMinRangeEditField
        function NMinRangeEditFieldValueChanged(app, event)
            value = app.NMinRangeEditField.Value;
            app.range1(1)=value;
        end

        % Value changed function: NMaxRangeEditField
        function NMaxRangeEditFieldValueChanged(app, event)
            value = app.NMaxRangeEditField.Value;
            app.range1(2)=value;
        end

        % Value changed function: RMaxRangeEditField
        function RMaxRangeEditFieldValueChanged(app, event)
            value = app.RMaxRangeEditField.Value;
            app.range2(2)=value;
        end

        % Value changed function: PostChEditField
        function PostChEditFieldValueChanged(app, event)
            value = app.PostChEditField.Value;
            app.r1Slice=value;
        end

        % Value changed function: NChEditField
        function NChEditFieldValueChanged(app, event)
            value = app.NChEditField.Value;
            app.nSlice=value;
        end

        % Value changed function: PreChEditField
        function PreChEditFieldValueChanged(app, event)
            value = app.PreChEditField.Value;
            app.r2Slice=value;
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            
            value = app.FileNameEditField.Value;
            app.dataFile=value;
        end

        % Button pushed function: loaddatafromworkspaceButton
        function loaddatafromworkspaceButtonPushed(app, event)
            %load ('loadData.m', 'newCenters', 'mu', 'ribbon', 'data');
            
            window=evalin('base','f');
            updateWindow(window);
            
            app.newCenters=evalin('base','f.UserData(1).Nucleus(:,1:3)');
            app.mu=evalin('base','f.UserData(1).Nucleus(:,4)');
            app.plainData=evalin('base','allData');
            
            app.voxel=evalin('base','voxel');
            app.dimensions=evalin('base','dimensions');
            
            
            app.ribbon=struct([]);
            
            app.ribbon(1).grouped=evalin('base','f.UserData(1).Ribbon');
            app.ribbon(2).grouped=evalin('base','f.UserData(2).Ribbon');
            
            app.PreChEditField.Value=evalin('base','presynaptic');
            app.PostChEditField.Value=evalin('base','postsynaptic');
            app.fileNum=evalin('base','fileNum');
            
            app.tiffData=struct();
            app.tiffData=evalin('base','tiffData');


            [app.presynapticPositions,app.postsynapticPositions]=ClusteredToSlice(app.ribbon,app.dimensions);

%             app.presynapticPositions=evalin('base','sendPre');
%             app.postsynapticPositions=evalin('base','sendPost');
            
            app.discardedRPre=[];
            app.discardedRPost=[];
            %updateT45(app);

            
        end

        % Callback function
        function GraphSliceDropDownValueChanged(app, event)
            value = app.GraphSliceDropDown.Value;
             
        end

        % Callback function
        function GraphSliceEditFieldValueChanged(app, event)
            
        end

        % Value changed function: GraphSliceSpinner
        function GraphSliceSpinnerValueChanged(app, event)
            value = app.GraphSliceSpinner.Value;
            channel=str2double(app.ChannelDropDown.Value);
            graphFinal(app,value,channel);
            
%             graphRibbon(app.UIAxes4,app.UIAxes5,app.UIAxes6,app.presynapticPositions(value).points,app.data(:,:,round(value),app.PreChEditField.Value),app.filteredData(:,:,round(value),app.PreChEditField.Value));
%                         graphRibbon(app.UIAxes7,app.UIAxes8,app.UIAxes9,app.postsynapticPositions(value).points,app.data(:,:,round(value),app.PostChEditField.Value),app.filteredData(:,:,round(value),app.PostChEditField.Value));
% 
% %             
% %             graphDetected(app.UIAxes7,app.UIAxes8,app.UIAxes9,app.newCenters,app.mu,app.ribbon,app.data(:,:,double(round(value)),app.PreChEditField.Value),round(value), app.NChEditField.Value,app.PreChEditField.Value,app.PostChEditField.Value, ...
% %             app.filteredData(:,:,round(value),app.PreChEditField.Value),[app.NMinRangeEditField.Value,app.NMaxRangeEditField.Value],[app.RMinRangeEditField.Value,app.RMaxRangeEditField.Value],app.NFirstEditField.Value,app.presynapticPositions,app.postsynapticPositions);
%             
%             graphNuclei(app.UIAxes10,app.UIAxes11,app.UIAxes12,app.newCenters,app.mu,app.data(:,:,round(value),app.NChEditField.Value),round(value),app.filteredData(:,:,round(value)));


        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.TextArea.Value="Loading in Data";
            pause(.1);
            [app.voxel,app.dimensions,app.minMax,app.plainData]=initialize(app.FileNameEditField.Value,app.NChEditField.Value,app.PreChEditField.Value,app.PostChEditField.Value,app.LastChEditField.Value);
            app.TextArea_2.Value=strcat("The pixel dimensions are: ",string(app.voxel(1))," micrometers(x) by ",string(app.voxel(2))," micrometers(y) by ",string(app.voxel(3))," micrometers(z)");
            app.TextArea.Value="Done Loading in Data";
        end

        % Callback function
        function LookatChannelDropDownValueChanged(app, event)
            
        end

        % Button pushed function: Graph1sliceButton
        function Graph1sliceButtonPushed(app, event)

            graphOne(app);
            
            
        end

        % Value changed function: LookatSliceSpinner
        function LookatSliceSpinnerValueChanged(app, event)
            value = app.LookatSliceSpinner.Value;
            
            if value>app.dimensions(3)
                app.LookatSliceSpinner.Value=app.dimensions(3);
            end
            graphOne(app);
        end

        % Value changed function: ShowCirclesButton
        function ShowCirclesButtonValueChanged(app, event)
            value = app.ShowCirclesButton.Value;            
            showCircles(app,value);
            
        end

        % Value changing function: ContrastSlider
        function ContrastSliderValueChanging(app, event)
             changingValue = event.Value;
             app.P13.CData=contrastFunc(app,changingValue,app.P13BrightnessAdjusted);

        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            [app.P13BrightnessAdjusted,app.P13.CData]=brightnessFunc(app,changingValue,app.P13Original);
        end

        % Callback function
        function Graph3DpixelsButtonPushed(app, event)
            [app.graphPre,app.graphPost,app.S7,app.S8]=graph3D(app.UIAxes16,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,str2double(app.RRadEditField.Value));
        end

        % Value changed function: Graph3DDropDown
        function Graph3DDropDownValueChanged(app, event)
            value = app.Graph3DDropDown.Value;
            if value=='Pixels'
                [app.graphPre,app.graphPost,app.graphNuc,app.S7,app.S8]=graph3D(app.UIAxes16,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,0,str2double(app.RRadEditField.Value));
                app.UIAxes16.XLabel.String='X(Pixels)';
                app.UIAxes16.YLabel.String='Y(Pixels)';
                app.UIAxes16.ZLabel.String='Z(Slice)';
            elseif value=='Scaled'
                [app.graphPre,app.graphPost,app.graphNuc,app.S7,app.S8]=graph3D(app.UIAxes16,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,1,str2double(app.RRadEditField.Value));
                app.UIAxes16.XLabel.String='X(micron)';
                app.UIAxes16.YLabel.String='Y(micron)';
                app.UIAxes16.ZLabel.String='Z(micron)';
            end

        end

        % Callback function
        function ButtonPushed(app, event)
            app.GraphDropDown.Items=["All Associated Nuclei","another"];
            app.GraphDropDown.ItemsData=[1,2];
        end

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
            value = app.GraphDropDown.Value;
            associatedChanged(app);
        end

        % Button pushed function: 
        % SetCirclesdoNOTexitthiswindowButton
        function SetCirclesdoNOTexitthiswindowButtonPushed(app, event)
            toGraphML(app);
            
        end

        % Button pushed function: UseCirclesButton
        function UseCirclesButtonPushed(app, event)
            if app.ChannelDropDown.Value=='1'
                settingsArrayN=bestFitSettingsN(app.MLFigure,app.plainData(:,:,app.LookatSliceSpinner.Value,str2double(app.ChannelDropDown.Value)),app.NThreshEditField.Value,[app.NMedEditField.Value,app.NMedEditField.Value], ...
                    [app.NMinRangeEditField.Value,app.NMaxRangeEditField.Value],[str2double(app.NRadmaxEditField.Value)-40,str2double(app.NRadmaxEditField.Value)] ...
                    ,app.NSensitivityEditField.Value,app.minMax(2,1),app.minMax(2,2));
            elseif app.ChannelDropDown.Value=='2'
                settingsArrayR1=bestFitSettingsR(app.MLFigure,app.plainData(:,:,app.LookatSliceSpinner.Value,str2double(app.ChannelDropDown.Value)),[app.RMedEditField.Value,app.RMedEditField.Value], ...
                    app.PreThreshEditField.Value,app.EpsilonEditField.Value,app.MinRsizeEditField.Value,[app.RMinRangeEditField.Value,app.RMaxRangeEditField.Value] ...
                    ,str2double(app.RRadEditField.Value),app.minMax(3,1),app.minMax(3,2))
            elseif app.ChannelDropDown.Value=='3'
                
            end
        end

        % Value changed function: BackgroundDropDown
        function BackgroundDropDownValueChanged(app, event)
            value = app.BackgroundDropDown.Value;
            f=figure;
            a=axes(f);
            
            [A,B,C,~,~]=graph3D(a,app.ribbon,app.discardedRPre,app.discardedRPost,app.newCenters,app.mu,app.voxel,0,str2double(app.RRadEditField.Value));
            
            set(C(:,1),'FaceAlpha',0,'EdgeColor','r','EdgeAlpha',.15);
            set(A(:,1),'FaceAlpha',.4);
            set(B(:,1),'FaceAlpha',.4);
            
            m=[app.NChEditField.Value,app.PreChEditField.Value,app.PostChEditField.Value];
            g=graphFinal3D(a,app.plainData(:,:,:,m(str2double(value))),app.NFirstEditField.Value,app.NLastEditField.Value,1);
            set(f,'WindowScrollWheelFcn',{@getThatScroll,g});
            

        end

        % Value changed function: CheckThatDataDropDown
        function CheckThatDataDropDownValueChanged(app, event)
            value = app.CheckThatDataDropDown.Value;
                        

            app.TextArea.Value="Graphing...";
            drawnow
            tic
            f=figure;
            a=axes(f);


            m=[app.NChEditField.Value,app.PreChEditField.Value,app.PostChEditField.Value];
            g=graphFinal3D(a,app.plainData(:,:,:,m(str2double(value))),app.NFirstEditField.Value,app.NLastEditField.Value,0,app);


            app.Scat3D=gobjects();
            view(a,0,90)
            for slice=1:sum(isgraphics(g))
                [Radii,Centers,~,~]=findStats(app,str2double(app.CheckThatDataDropDown.Value),slice);

                if size(Centers,1)>0
                
                    [app.Scat3D(slice,1)]=graphCircles3D(a,Centers,Radii+8,slice,'r');
                end
            end
            
            set(f,'WindowScrollWheelFcn',{@getThatScroll,g,app});
            set(f,'WindowButtonDownFcn',{@addRibbon,g,app});
            
            indices=find(isgraphics(app.Scat3D(:,1))==1);
            set(app.Scat3D(indices,1),'ButtonDownFcn',{@removeRibbon,app,g});
            toc
            app.TextArea.Value="Done graphing";
        end

        % Button pushed function: UpdateRibbonsButton
        function UpdateRibbonsButtonPushed(app, event)
            updateT45(app);
        end

        % Button pushed function: SavetoNewFolderButton
        function SavetoNewFolderButtonPushed(app, event)
            fileName=app.SaveToEditField.Value;
            nucleusData=[app.newCenters,app.mu];
            ribbonData=app.ribbon;
            saveData(app,fileName,nucleusData,ribbonData,app.voxel,app.R1Scat,app.R2Scat,app.NAssociated,app.RAssociated,app.PreChEditField.Value,app.PostChEditField.Value,app.dimensions);
        end

        % Value changed function: CheckData3DDropDown
        function CheckData3DDropDownValueChanged(app, event)
            value = app.CheckData3DDropDown.Value;
            proxyFunc(app)
            
            
        end

        % Callback function
        function OverwriteDataButtonPushed(app, event)
           
        end

        % Value changed function: LoadinMiniDataSpinner
        function LoadinMiniDataSpinnerValueChanged(app, event)
            value = app.LoadinMiniDataSpinner.Value;
            [app.plainData,app.minMax,app.dimensions]=loadMiniData(value);
        end

        % Button pushed function: SavesettingsfromMiniDataButton
        function SavesettingsfromMiniDataButtonPushed(app, event)
            saveSettings(app.PreChEditField.Value,app.PostChEditField.Value,app.LoadinMiniDataSpinner.Value,app.NFirstEditField.Value,app.NLastEditField.Value,app.NMinRangeEditField.Value,app.NMaxRangeEditField.Value, ...
                app.NThreshEditField.Value,app.RFirstEditField.Value,app.RLastEditField.Value,app.RMinRangeEditField.Value,app.RMaxRangeEditField.Value, ...
                app.PreThreshEditField.Value,app.PostThreshEditField.Value);
        end

        % Value changed function: NewSliceValidationDropDown
        function NewSliceValidationDropDownValueChanged(app, event)
            value = app.NewSliceValidationDropDown.Value;
            m=[app.NChEditField.Value,app.PreChEditField.Value,app.PostChEditField.Value];
            primaryChannel=m(str2double(app.NewSliceValidationDropDown.Value));
            sValFig=sliceValidation(app,app.plainData,primaryChannel);
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            value = app.CheckData3DDropDown.Value;
            proxyFunc(app)
        end

        % Value changed function: TiffSliceValidationDropDown
        function TiffSliceValidationDropDownValueChanged(app, event)
            value = app.TiffSliceValidationDropDown.Value;
            m=[app.NChEditField.Value,app.PreChEditField.Value,app.PostChEditField.Value];
            primaryChannel=m(str2double(app.TiffSliceValidationDropDown.Value));
            sValFig=sliceValidationTiff(app,app.plainData,primaryChannel,app.tiffData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreliminaryViewingTab = uitab(app.TabGroup);
            app.PreliminaryViewingTab.Title = 'Preliminary Viewing';
            app.ShowCirclesButton = uibutton(app.PreliminaryViewingTab, 'state');
            app.ShowCirclesButton.ValueChangedFcn = createCallbackFcn(app, @ShowCirclesButtonValueChanged, true);
            app.ShowCirclesButton.Text = 'Show Circles';
            app.ContrastSliderLabel = uilabel(app.PreliminaryViewingTab);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.PreliminaryViewingTab);
            app.ContrastSlider.Limits = [0 0.4];
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @ContrastSliderValueChanging, true);
            app.ContrastSlider.Tooltip = {'Adjust Contrast of viewing window. This is just so the user can see better and has no effect on the actual data.'};
            app.BrightnessSliderLabel = uilabel(app.PreliminaryViewingTab);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.PreliminaryViewingTab);
            app.BrightnessSlider.Limits = [0 1];
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.BrightnessSlider.Tooltip = {'Adjust Brightness of viewing window. This is just so the user can see better and has no effect on the actual data.'};
            app.UIAxes13 = uiaxes(app.PreliminaryViewingTab);
            title(app.UIAxes13, '')
            xlabel(app.UIAxes13, '')
            ylabel(app.UIAxes13, '')
            app.UIAxes13.XTick = [];
            app.UIAxes13.YTick = [];
            app.UIAxes14 = uiaxes(app.PreliminaryViewingTab);
            title(app.UIAxes14, '')
            xlabel(app.UIAxes14, '')
            ylabel(app.UIAxes14, '')
            app.UIAxes14.XTick = [];
            app.UIAxes14.YTick = [];
            app.UIAxes15 = uiaxes(app.PreliminaryViewingTab);
            title(app.UIAxes15, '')
            xlabel(app.UIAxes15, 'X')
            ylabel(app.UIAxes15, 'Y')
            app.UIAxes15.XTickLabel = '';
            app.UIAxes15.YTickLabel = '';
            app.SetCirclesdoNOTexitthiswindowButton = uibutton(app.PreliminaryViewingTab, 'push');
            app.SetCirclesdoNOTexitthiswindowButton.ButtonPushedFcn = createCallbackFcn(app, @SetCirclesdoNOTexitthiswindowButtonPushed, true);
            app.SetCirclesdoNOTexitthiswindowButton.Text = 'Set Circles(do NOT exit this window)';
            app.UseCirclesButton = uibutton(app.PreliminaryViewingTab, 'push');
            app.UseCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @UseCirclesButtonPushed, true);
            app.UseCirclesButton.Text = 'Use Circles';
            app.AfterAnalysisTab = uitab(app.TabGroup);
            app.AfterAnalysisTab.Title = 'After Analysis';
            app.UIAxes10 = uiaxes(app.AfterAnalysisTab);
            title(app.UIAxes10, '')
            xlabel(app.UIAxes10, '')
            ylabel(app.UIAxes10, '')
            app.UIAxes10.XTick = [];
            app.UIAxes10.YTick = [];
            app.UIAxes11 = uiaxes(app.AfterAnalysisTab);
            title(app.UIAxes11, '')
            xlabel(app.UIAxes11, '')
            ylabel(app.UIAxes11, '')
            app.UIAxes11.XTick = [];
            app.UIAxes11.YTick = [];
            app.UIAxes12 = uiaxes(app.AfterAnalysisTab);
            title(app.UIAxes12, '')
            xlabel(app.UIAxes12, '')
            ylabel(app.UIAxes12, '')
            app.UIAxes12.XTick = [];
            app.UIAxes12.YTick = [];
            app.CheckThatDataDropDownLabel = uilabel(app.AfterAnalysisTab);
            app.CheckThatDataDropDownLabel.Text = 'Check That Data!';
            app.CheckThatDataDropDown = uidropdown(app.AfterAnalysisTab);
            app.CheckThatDataDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic'};
            app.CheckThatDataDropDown.ItemsData = {'1', '2', '3'};
            app.CheckThatDataDropDown.ValueChangedFcn = createCallbackFcn(app, @CheckThatDataDropDownValueChanged, true);
            app.CheckThatDataDropDown.Tooltip = {'This is extremely important in allowing the user to check and edit the final data. The user MUST run through each of the channels to verify that the data is correct. A ribbon can be added with a double left click, and they can be taken away with a right click(although this is finicky and hte viewing angle may need to be rotated with the rotate tool before it works)'};
            app.CheckThatDataDropDown.Value = '1';
            app.CheckData3DDropDownLabel = uilabel(app.AfterAnalysisTab);
            app.CheckData3DDropDownLabel.Text = 'Check Data 3D';
            app.CheckData3DDropDown = uidropdown(app.AfterAnalysisTab);
            app.CheckData3DDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic', 'Extra'};
            app.CheckData3DDropDown.ItemsData = {'1', '2', '3', '4'};
            app.CheckData3DDropDown.ValueChangedFcn = createCallbackFcn(app, @CheckData3DDropDownValueChanged, true);
            app.CheckData3DDropDown.Tooltip = {'Look at the nuclei and ribbons in a rendedred image. Nuclei can eb easily added here.'};
            app.CheckData3DDropDown.Value = '1';
            app.NewSliceValidationDropDownLabel = uilabel(app.AfterAnalysisTab);
            app.NewSliceValidationDropDownLabel.Text = 'New Slice Validation';
            app.NewSliceValidationDropDown = uidropdown(app.AfterAnalysisTab);
            app.NewSliceValidationDropDown.Items = {'Presynaptic', 'Postsynaptic'};
            app.NewSliceValidationDropDown.ItemsData = {'2', '3'};
            app.NewSliceValidationDropDown.ValueChangedFcn = createCallbackFcn(app, @NewSliceValidationDropDownValueChanged, true);
            app.NewSliceValidationDropDown.Value = '2';
            app.Button = uibutton(app.AfterAnalysisTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.TiffSliceValidationDropDownLabel = uilabel(app.AfterAnalysisTab);
            app.TiffSliceValidationDropDownLabel.Text = 'Tiff Slice Validation';
            app.TiffSliceValidationDropDown = uidropdown(app.AfterAnalysisTab);
            app.TiffSliceValidationDropDown.Items = {'Presynaptic', 'Postsynaptic'};
            app.TiffSliceValidationDropDown.ItemsData = {'2', '3'};
            app.TiffSliceValidationDropDown.ValueChangedFcn = createCallbackFcn(app, @TiffSliceValidationDropDownValueChanged, true);
            app.TiffSliceValidationDropDown.Value = '2';
            app.DGraphTab = uitab(app.TabGroup);
            app.DGraphTab.Title = '3D Graph';
            app.UIAxes16 = uiaxes(app.DGraphTab);
            title(app.UIAxes16, '3D Graph')
            xlabel(app.UIAxes16, 'X(micron)')
            ylabel(app.UIAxes16, 'Y(micron)')
            app.Graph3DDropDownLabel = uilabel(app.DGraphTab);
            app.Graph3DDropDownLabel.Text = 'Graph 3D';
            app.Graph3DDropDown = uidropdown(app.DGraphTab);
            app.Graph3DDropDown.Items = {'Pixels', 'Scaled', ''};
            app.Graph3DDropDown.ValueChangedFcn = createCallbackFcn(app, @Graph3DDropDownValueChanged, true);
            app.Graph3DDropDown.Value = 'Scaled';
            app.BackgroundDropDownLabel = uilabel(app.DGraphTab);
            app.BackgroundDropDownLabel.Text = 'Background:';
            app.BackgroundDropDown = uidropdown(app.DGraphTab);
            app.BackgroundDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic'};
            app.BackgroundDropDown.ItemsData = {'1', '2', '3'};
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundDropDownValueChanged, true);
            app.BackgroundDropDown.Value = '1';
            app.NoOverlapTab = uitab(app.TabGroup);
            app.NoOverlapTab.Title = 'NoOverlap';
            app.UIAxes17 = uiaxes(app.NoOverlapTab);
            title(app.UIAxes17, 'After Removing Overlapping Nuclei')
            xlabel(app.UIAxes17, 'X(micron)')
            ylabel(app.UIAxes17, 'Y(micron)')
            app.GraphDropDownLabel = uilabel(app.NoOverlapTab);
            app.GraphDropDownLabel.Text = 'Graph:';
            app.GraphDropDown = uidropdown(app.NoOverlapTab);
            app.GraphDropDown.Items = {'All Associated Nuclei'};
            app.GraphDropDown.ItemsData = {'1'};
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
            app.GraphDropDown.Value = '1';
            app.UpdateRibbonsButton = uibutton(app.NoOverlapTab, 'push');
            app.UpdateRibbonsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateRibbonsButtonPushed, true);
            app.UpdateRibbonsButton.Tooltip = {'This may be redundant, but press this before you save the data and before you view this or the next tab, as the ribbon data changes with user input.'};
            app.UpdateRibbonsButton.Text = 'Update Ribbons';
            app.RepresentativeTab = uitab(app.TabGroup);
            app.RepresentativeTab.Title = 'Representative';
            app.UIAxes18 = uiaxes(app.RepresentativeTab);
            title(app.UIAxes18, 'Representative Nucleus and Ribbon Positions')
            xlabel(app.UIAxes18, 'X(micron)')
            ylabel(app.UIAxes18, 'Y(micron)')
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.FileNameEditFieldLabel = uilabel(app.SettingsTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SettingsTab, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Tooltip = {'Enter the name of the data file you want to load'};
            app.FileNameEditField.Value = 'IB09MidControlLeft2.czi';
            app.NThreshEditFieldLabel = uilabel(app.SettingsTab);
            app.NThreshEditFieldLabel.Text = 'N Thresh';
            app.NThreshEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NThreshEditField.ValueChangedFcn = createCallbackFcn(app, @NThreshEditFieldValueChanged, true);
            app.NThreshEditField.Tooltip = {'Threshold when doing image enhancement on nuclei. Recommended: .06-.11'};
            app.NThreshEditField.Value = 0.08;
            app.PreThreshEditFieldLabel = uilabel(app.SettingsTab);
            app.PreThreshEditFieldLabel.Text = 'Pre Thresh';
            app.PreThreshEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PreThreshEditField.ValueChangedFcn = createCallbackFcn(app, @PreThreshEditFieldValueChanged, true);
            app.PreThreshEditField.Tooltip = {'Threshold when doing image enhancement on presynaptic ribbons. Recommended: .06-.11'};
            app.PreThreshEditField.Value = 0.06;
            app.PostThreshEditFieldLabel = uilabel(app.SettingsTab);
            app.PostThreshEditFieldLabel.Text = 'Post Thresh';
            app.PostThreshEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PostThreshEditField.ValueChangedFcn = createCallbackFcn(app, @PostThreshEditFieldValueChanged, true);
            app.PostThreshEditField.Tooltip = {'Threshold when doing image enhancement on postsynaptic densities. Recommended: .06-.11'};
            app.PostThreshEditField.Value = 0.07;
            app.NMinRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.NMinRangeEditFieldLabel.Text = 'N Min Range';
            app.NMinRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NMinRangeEditField.ValueChangedFcn = createCallbackFcn(app, @NMinRangeEditFieldValueChanged, true);
            app.NMinRangeEditField.Tooltip = {'The minimum X value(in pixels) which IHC nuclei occur.'};
            app.NMinRangeEditField.Value = 300;
            app.NMaxRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.NMaxRangeEditFieldLabel.Text = 'N Max Range';
            app.NMaxRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NMaxRangeEditField.ValueChangedFcn = createCallbackFcn(app, @NMaxRangeEditFieldValueChanged, true);
            app.NMaxRangeEditField.Tooltip = {'The maximum X value(in pixels) which IHC nuclei occur.'};
            app.NMaxRangeEditField.Value = 400;
            app.RMinRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.RMinRangeEditFieldLabel.Text = 'R Min Range';
            app.RMinRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RMinRangeEditField.ValueChangedFcn = createCallbackFcn(app, @RMinRangeEditFieldValueChanged, true);
            app.RMinRangeEditField.Tooltip = {'The minimum X value(in pixels) which '; 'ribbons occur.'};
            app.RMinRangeEditField.Value = 350;
            app.RMaxRangeEditFieldLabel = uilabel(app.SettingsTab);
            app.RMaxRangeEditFieldLabel.Text = 'R Max Range';
            app.RMaxRangeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RMaxRangeEditField.ValueChangedFcn = createCallbackFcn(app, @RMaxRangeEditFieldValueChanged, true);
            app.RMaxRangeEditField.Tooltip = {'The maximum X value(in pixels) which '; 'ribbons occur.'};
            app.RMaxRangeEditField.Value = 450;
            app.NChEditFieldLabel = uilabel(app.SettingsTab);
            app.NChEditFieldLabel.Text = 'N Ch';
            app.NChEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NChEditField.ValueChangedFcn = createCallbackFcn(app, @NChEditFieldValueChanged, true);
            app.NChEditField.Tooltip = {'What channel of the dataset the nuclei are on.'};
            app.NChEditField.Value = 1;
            app.PreChEditFieldLabel = uilabel(app.SettingsTab);
            app.PreChEditFieldLabel.Text = 'Pre Ch';
            app.PreChEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PreChEditField.ValueChangedFcn = createCallbackFcn(app, @PreChEditFieldValueChanged, true);
            app.PreChEditField.Tooltip = {'What channel of the data set the presynaptic ribbons are on.(In our case, this channel will also show the nuclei due to the antibodies used)'};
            app.PreChEditField.Value = 2;
            app.PostChEditFieldLabel = uilabel(app.SettingsTab);
            app.PostChEditFieldLabel.Text = 'Post Ch';
            app.PostChEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PostChEditField.ValueChangedFcn = createCallbackFcn(app, @PostChEditFieldValueChanged, true);
            app.PostChEditField.Tooltip = {'Which channel of the data set the postsynaptic densities are on.'};
            app.PostChEditField.Value = 3;
            app.LoadDataButton = uibutton(app.SettingsTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Use this button to load in the data and gather some basic statistics'};
            app.LoadDataButton.Text = 'Load Data';
            app.LookatSliceSpinnerLabel = uilabel(app.SettingsTab);
            app.LookatSliceSpinnerLabel.Text = 'Look at Slice:';
            app.LookatSliceSpinner = uispinner(app.SettingsTab);
            app.LookatSliceSpinner.ValueChangedFcn = createCallbackFcn(app, @LookatSliceSpinnerValueChanged, true);
            app.LookatSliceSpinner.Tooltip = {'This shows a preliminary viewing of the data along with the detected organs. The image is shown on the "Preliminary Viewing" tab'};
            app.LookatSliceSpinner.Value = 1;
            app.NSensitivityEditFieldLabel = uilabel(app.SettingsTab);
            app.NSensitivityEditFieldLabel.Text = 'N Sensitivity';
            app.NSensitivityEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NSensitivityEditField.Tooltip = {'Sensitivity when looking for nuclei. Turning it up means more detected nuclei, turning it down means fewer.'};
            app.NSensitivityEditField.Value = 0.972;
            app.Graph1sliceButton = uibutton(app.SettingsTab, 'push');
            app.Graph1sliceButton.ButtonPushedFcn = createCallbackFcn(app, @Graph1sliceButtonPushed, true);
            app.Graph1sliceButton.Tooltip = {'Graph a slice on "Preliminary Viewing" tab. Must be done after loading in the data.'};
            app.Graph1sliceButton.Text = 'Graph 1 slice';
            app.LastChEditFieldLabel = uilabel(app.SettingsTab);
            app.LastChEditFieldLabel.Text = 'Last Ch';
            app.LastChEditField = uieditfield(app.SettingsTab, 'numeric');
            app.LastChEditField.Tooltip = {'The last channel(is not used, but must be correct otherwse an error may be thrown)'};
            app.LastChEditField.Value = 4;
            app.NMedEditFieldLabel = uilabel(app.SettingsTab);
            app.NMedEditFieldLabel.Text = 'N Med';
            app.NMedEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NMedEditField.Tooltip = {'Median range used for image nehancement. Recommended: 4-8'};
            app.NMedEditField.Value = 6;
            app.NRadmaxEditFieldLabel = uilabel(app.SettingsTab);
            app.NRadmaxEditFieldLabel.Text = 'N Rad max';
            app.NRadmaxEditField = uieditfield(app.SettingsTab, 'text');
            app.NRadmaxEditField.Tooltip = {'Maximum nuclei radius in pixels. The minimum is automatically inputted as this value -40.'};
            app.NRadmaxEditField.Value = '100';
            app.RRadEditFieldLabel = uilabel(app.SettingsTab);
            app.RRadEditFieldLabel.Text = 'R Rad';
            app.RRadEditField = uieditfield(app.SettingsTab, 'text');
            app.RRadEditField.Tooltip = {'estimated ribbon radius. Just used for graphing and has no effect on the computer detections of ribbons.'};
            app.RRadEditField.Value = '40';
            app.RMedEditFieldLabel = uilabel(app.SettingsTab);
            app.RMedEditFieldLabel.Text = 'R Med';
            app.RMedEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RMedEditField.Tooltip = {'Median range used for image nehancement. Recommended: 4-8'};
            app.RMedEditField.Value = 5;
            app.EpsilonEditFieldLabel = uilabel(app.SettingsTab);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.SettingsTab, 'numeric');
            app.EpsilonEditField.Tooltip = {'How far apart in pixels ribbons need to be to be considered separate. Recommended: 4-8'};
            app.EpsilonEditField.Value = 4;
            app.MinRsizeEditFieldLabel = uilabel(app.SettingsTab);
            app.MinRsizeEditFieldLabel.Text = 'Min R size';
            app.MinRsizeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.MinRsizeEditField.Tooltip = {'How many pixels each ribbon must take up after image enhancement to be considered a ribbon instance. Recommended: 4-8'};
            app.MinRsizeEditField.Value = 5;
            app.ChannelDropDownLabel = uilabel(app.SettingsTab);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.SettingsTab);
            app.ChannelDropDown.Items = {'Nucleus', 'Presynaptic', 'Postsynaptic', 'Extra'};
            app.ChannelDropDown.ItemsData = {'1', '2', '3', '4'};
            app.ChannelDropDown.Tooltip = {'What channel you wish to see(applies to both the "Preliminary Viewing" tab and the "After Analysis" tab)'};
            app.ChannelDropDown.Value = '2';
            app.NFirstEditFieldLabel = uilabel(app.SettingsTab);
            app.NFirstEditFieldLabel.Text = 'N First';
            app.NFirstEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NFirstEditField.ValueChangedFcn = createCallbackFcn(app, @NFirstEditFieldValueChanged, true);
            app.NFirstEditField.Tooltip = {'The first slice to look for nuclei on.'};
            app.NFirstEditField.Value = 1;
            app.RFirstEditFieldLabel = uilabel(app.SettingsTab);
            app.RFirstEditFieldLabel.Text = 'R First';
            app.RFirstEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RFirstEditField.Tooltip = {'The first slice to look for ribbons on.'};
            app.RFirstEditField.Value = 1;
            app.RLastEditFieldLabel = uilabel(app.SettingsTab);
            app.RLastEditFieldLabel.Text = 'R Last';
            app.RLastEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RLastEditField.Tooltip = {'How many slices should be ignored at the end of the dataset.'};
            app.RLastEditField.Value = 1;
            app.NLastEditFieldLabel = uilabel(app.SettingsTab);
            app.NLastEditFieldLabel.Text = 'N Last';
            app.NLastEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NLastEditField.ValueChangedFcn = createCallbackFcn(app, @NLastEditFieldValueChanged, true);
            app.NLastEditField.Tooltip = {'How many slices should be ignored at the end of the dataset.'};
            app.NLastEditField.Value = 1;
            app.TextArea_2 = uitextarea(app.SettingsTab);
            app.MoreSettingsTab = uitab(app.TabGroup2);
            app.MoreSettingsTab.Title = 'More Settings';
            app.GraphSliceSpinnerLabel = uilabel(app.MoreSettingsTab);
            app.GraphSliceSpinnerLabel.Text = 'Graph Slice:';
            app.GraphSliceSpinner = uispinner(app.MoreSettingsTab);
            app.GraphSliceSpinner.ValueChangedFcn = createCallbackFcn(app, @GraphSliceSpinnerValueChanged, true);
            app.GraphSliceSpinner.Tooltip = {'Graph slice on "After Analysis" tab. Must be done after pressing the  "Update Starting Info" button.'};
            app.SaveToEditFieldLabel = uilabel(app.MoreSettingsTab);
            app.SaveToEditFieldLabel.Text = 'Save To:';
            app.SaveToEditField = uieditfield(app.MoreSettingsTab, 'text');
            app.SaveToEditField.Tooltip = {'Save the data to some xcel and matlab files which can be used later. The files are placed inside a folder, and the suffixes of the file indicate what type of data is stored in it.'};
            app.SavetoNewFolderButton = uibutton(app.MoreSettingsTab, 'push');
            app.SavetoNewFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoNewFolderButtonPushed, true);
            app.SavetoNewFolderButton.Tooltip = {'Press this button to save the data.'};
            app.SavetoNewFolderButton.Text = 'Save to New Folder';
            app.UpdatestartingInfoButton = uibutton(app.MoreSettingsTab, 'push');
            app.UpdatestartingInfoButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatestartingInfoButtonPushed, true);
            app.UpdatestartingInfoButton.Tooltip = {'Use this button to use the user input settings to automatically detect nuclei and ribbons.This will go through all the slices and takes ~3-10 mins depending on CPU, settings, and dataset size.'};
            app.UpdatestartingInfoButton.Text = 'Update starting Info.';
            app.loaddatafromworkspaceButton = uibutton(app.MoreSettingsTab, 'push');
            app.loaddatafromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @loaddatafromworkspaceButtonPushed, true);
            app.loaddatafromworkspaceButton.Text = 'load data(from workspace)';
            app.LoadinMiniDataSpinnerLabel = uilabel(app.MoreSettingsTab);
            app.LoadinMiniDataSpinnerLabel.Text = 'Load in Mini Data';
            app.LoadinMiniDataSpinner = uispinner(app.MoreSettingsTab);
            app.LoadinMiniDataSpinner.ValueChangedFcn = createCallbackFcn(app, @LoadinMiniDataSpinnerValueChanged, true);
            app.SavesettingsfromMiniDataButton = uibutton(app.MoreSettingsTab, 'push');
            app.SavesettingsfromMiniDataButton.ButtonPushedFcn = createCallbackFcn(app, @SavesettingsfromMiniDataButtonPushed, true);
            app.SavesettingsfromMiniDataButton.Text = 'Save settings from Mini Data';
            app.TextArea = uitextarea(app.UIFigure);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ribbonAnalysisGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end